classdef finance_tracker_app_v4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FinanceTrackerUIFigure   matlab.ui.Figure
        typeDropDownLabel        matlab.ui.control.Label
        categoryDropDown         matlab.ui.control.DropDown
        categoryDropDownLabel    matlab.ui.control.Label
        dateDatePicker           matlab.ui.control.DatePicker
        dateDatePickerLabel      matlab.ui.control.Label
        valueEditField           matlab.ui.control.NumericEditField
        valueEditFieldLabel      matlab.ui.control.Label
        OKButton                 matlab.ui.control.Button
        categoryEditField        matlab.ui.control.EditField
        categoryEditFieldLabel   matlab.ui.control.Label
        TabGroup                 matlab.ui.container.TabGroup
        TransactionsTab          matlab.ui.container.Tab
        Lamp2                    matlab.ui.control.Lamp
        balanceEditField         matlab.ui.control.NumericEditField
        balanceEditField_2Label  matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        removeselectedButton     matlab.ui.control.Button
        GoalsTab                 matlab.ui.container.Tab
        goalsUITable             matlab.ui.control.Table
        ReportsTab               matlab.ui.container.Tab
        reportTextArea           matlab.ui.control.TextArea
        generatereportButton     matlab.ui.control.Button
        reportAxes               matlab.ui.control.UIAxes
        typeDropDown             matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        account % to hold the "Account" object
    end
    
    methods (Access = private)
        
        function formatTable(app)
            data = app.UITable.Data; % get table data
            columnNames = app.UITable.ColumnName; % get column names
            typeIdx = strcmp(columnNames, 'type');% logical indexing
            % iterate over each row and set the background color based on type
            for ii = 1:size(data, 1)
                if strcmp(data{ii, typeIdx}, 'expense')
                    style_redBG = uistyle("BackgroundColor","#FBACBE"); % red bg
                    app.UITable.addStyle(style_redBG, "row", ii); % apply style
                elseif strcmp(data{ii, typeIdx}, 'income')
                    style_greenBG = uistyle("BackgroundColor","#B9D2B1"); % green bg
                    app.UITable.addStyle(style_greenBG, "row", ii); % apply style
                else
                    % style_whiteBG = uistyle("BackgroundColor","#FFFFF"); % white bg
                    % app.UITable.addStyle(style_whiteBG, "row", ii); % apply style
                end
            end
        end
        
        function balanceLamp(app)
            if app.account.Balance > 0
                app.Lamp2.Color = "green"; % green 
            elseif app.account.Balance < 0 % negative balance
                app.Lamp2.Color = "red";
            else
                app.Lamp2.Color = [.5, .5, .5]; % gray
            end
        end
        
        function updateTransactionList(app)
            transactions = app.account.getSummary(); % get transactions from "Account" class
            data = cell(length(transactions), 4); % create empty cell array with size
            
            % get column names and their indices
            columnNames = app.UITable.ColumnName;
            % logical indexing
            amountIdx = strcmp(columnNames, 'amount');
            categoryIdx = strcmp(columnNames, 'category');
            typeIdx = strcmp(columnNames, 'type');
            dateIdx = strcmp(columnNames, 'date');

            % iterate through transactions
            for ii = 1:length(transactions)
                transaction = transactions{ii};
                % assign values to data
                data{ii, amountIdx} = transaction.Amount; % get amount
                data{ii, categoryIdx} = transaction.Category; % get category
                data{ii, typeIdx} = lower(class(transaction)); % get class (income or expense)
                data{ii, dateIdx} = char(transaction.Date); % get date and convert to char
            end
            
            % assign data to UITable
            app.UITable.Data = data;
        end

        function updateGoalsList(app)
            goals = app.account.getGoalsSummary(); % get goals from "Account" class
            data = cell(length(goals), 4);

            % get column names and their indices
            columnNames = app.UITable.ColumnName;
            % logical indexing
            amountIdx = strcmp(columnNames, 'amount');
            categoryIdx = strcmp(columnNames, 'category');
            typeIdx = strcmp(columnNames, 'type');
            dateIdx = strcmp(columnNames, 'date');

            % iterate through goals
            for ii = 1:length(goals)
                goal = goals{ii};
                % assign values to data
                data{ii, amountIdx} = goal.Amount; % get amount
                data{ii, categoryIdx} = goal.Category; % get category
                data{ii, typeIdx} = lower(class(goal)); % get class (income or expense)
                data{ii, dateIdx} = char(goal.Date); % get date and convert to char
            end
            
            % assign data to UITable
            app.goalsUITable.Data = data;
        end

        function summary = summarizeBudget(app)
            totalIncome = sum([app.Incomes.Amount]);
            totalExpense = sum([app.Expenses.Amount]);
            balance = totalIncome - totalExpense;
            summary = struct('TotalIncome', totalIncome, 'TotalExpense', totalExpense, 'Balance', balance);
        end
        
        function report = generateTextReport(app)
            % get transactions from the account object
            transactions = app.account.getSummary();
            report = sprintf('Report on savings and current balance:\n\n');

            totalIncome = 0;
            totalExpense = 0;

            % iterate through transactions
            for i = 1:length(transactions)
                transaction = transactions{i};
                if isa(transaction, 'Income')
                    totalIncome = totalIncome + transaction.Amount;
                    report = sprintf('%sIncome: %0.2f | Category: %s | Date: %s\n', ... 
                        report, transaction.Amount, transaction.Category, transaction.Date);
                elseif isa(transaction, 'Expense')
                    totalExpense = totalExpense + transaction.Amount;
                    report = sprintf('%sExpense: %0.2f | Category: %s | Date: %s\n', ... 
                        report, transaction.Amount, transaction.Category, transaction.Date);
                end
            end

            % calculate balance
            balance = totalIncome - totalExpense;
            % append summary
            report = sprintf('%s\nTotal Income: %0.2f\nTotal Expense: %0.2f\nCurrent Balance: %0.2f\n', ...
                report, totalIncome, totalExpense, balance);
        end
        
        function generateGraphicalReport(app)
            % get transactions from the account object
            transactions = app.account.getSummary();
            n = length(transactions); % number of transactions
            
            % preallocate arrays
            dates = datetime.empty(n,0);
            values = zeros(n, 1);
            balance = 0;

            % iterate through transactions
            for i = 1:n
                transaction = transactions{i};
                if isa(transaction, 'Income')
                    balance = balance + transaction.Amount;
                elseif isa(transaction, 'Expense')
                    balance = balance - transaction.Amount;
                end
                dates = [dates; transaction.Date];
                values = [values; balance];
            end
            
            % plot on UIAxes
            plot(app.reportAxes, dates, values, '-o');
            title(app.reportAxes, 'history of savings');
            xlabel(app.reportAxes, 'date');
            ylabel(app.reportAxes, 'balance');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.account = Account("current account", 0); % initialize "Account"
            format longG; % turn off scientific notation
            % dialog box for initial account balance
            % prompt = {'enter initial account balance:'};
            % dlgtitle = 'initial account balance';
            % fieldsize = [1 45];
            % definput = {'0'};
            % answer = inputdlg(prompt,dlgtitle,fieldsize,definput)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            newValue = app.valueEditField.Value; % get input value
            newCategory = app.categoryEditField.Value; % get input text
            newType =  app.typeDropDown.Value; % get type (income, expense, goal)
            newDate = app.dateDatePicker.Value; % get date

            %newCategoryExpense = app.categoryDropDown.Value;

            % validate inputs
            if newValue == 0 || isnat(newDate)
                uialert(app.FinanceTrackerUIFigure, "please fill out all fields.", "input error");
                return; % exit
            end

            if strcmp(newType,  'expense') % if type is expense %newType == "expense"
                newCategory = app.categoryDropDown.Value;
                newExpense = Expense(newValue, newCategory, newDate);
                app.account.addExpense(newExpense);
                %newValue = -newValue; % negative sign
            elseif strcmp(newType, 'income') % if type is income
                newIncome = Income(newValue, newCategory, newDate);
                app.account.addIncome(newIncome);
            elseif strcmp(newType, 'goal') % if type is goal
                newGoal = Goal(newValue, newCategory, newDate);
                app.account.addGoal(newGoal);
                uialert(app.FinanceTrackerUIFigure, 'goal added to "Goals" tab', 'success', 'Icon','success');
                updateGoalsList(app);
            else
                errordlg("something went wrong - OKButtonPushed()", "OKButtonPushed() error")
            end

            % update UITable
            updateTransactionList(app)
            % update the balance
            app.balanceEditField.Value = app.account.Balance;
            % apply conditional formatting
            formatTable(app); 
            % update balance lamp color
            balanceLamp(app); 
        end

        % Button pushed function: removeselectedButton
        function removeselectedButtonPushed(app, event)
            selectedRow = app.UITable.Selection; % get selection of uiTable
            currentData = app.UITable.Data; % get the current data in the uiTable
            currentData(selectedRow, :) = []; % make the uiTable empty in selected row
            app.UITable.Data = currentData;
        end

        % Value changed function: typeDropDown
        function typeDropDownValueChanged(app, event)
            selectedType = app.typeDropDown.Value;
            if strcmp(selectedType, "expense")
                app.categoryEditField.Visible = "off";
                app.categoryEditFieldLabel.Visible = "off";
                app.categoryDropDown.Visible = "on";
                app.categoryDropDownLabel.Visible = "on";
            elseif strcmp(selectedType, "income")
                app.categoryEditField.Visible = "on";
                app.categoryEditFieldLabel.Visible = "on";
                app.categoryDropDown.Visible = "off";
                app.categoryDropDownLabel.Visible = "off";
            elseif strcmp(selectedType, "goal")
                app.categoryEditField.Visible = "on";
                app.categoryEditFieldLabel.Visible = "on";
                app.categoryDropDown.Visible = "off";
                app.categoryDropDownLabel.Visible = "off";
            else
                errordlg("something went wrong - DropDownValueChanged()", "DropDownValueChanged() error")
            end
        end

        % Button pushed function: generatereportButton
        function generatereportButtonPushed(app, event)
            % text report
            textReport = app.generateTextReport();
            app.reportTextArea.Value = textReport;

            % graphical report
            app.generateGraphicalReport();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FinanceTrackerUIFigure = uifigure('Visible', 'off');
            app.FinanceTrackerUIFigure.Name = 'Finance Tracker';
            app.typeDropDown = uidropdown(app.FinanceTrackerUIFigure);
            app.typeDropDown.Items = {'income', 'expense', 'goal'};
            app.typeDropDown.ValueChangedFcn = createCallbackFcn(app, @typeDropDownValueChanged, true);
            app.typeDropDown.Value = 'income';
            app.TabGroup = uitabgroup(app.FinanceTrackerUIFigure);
            app.TransactionsTab = uitab(app.TabGroup);
            app.TransactionsTab.Title = 'Transactions';
            app.removeselectedButton = uibutton(app.TransactionsTab, 'push');
            app.removeselectedButton.ButtonPushedFcn = createCallbackFcn(app, @removeselectedButtonPushed, true);
            app.removeselectedButton.Text = 'remove selected';
            app.UITable = uitable(app.TransactionsTab);
            app.UITable.ColumnName = {'amount'; 'category'; 'type'; 'date'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.balanceEditField_2Label = uilabel(app.TransactionsTab);
            app.balanceEditField_2Label.Text = 'balance:';
            app.balanceEditField = uieditfield(app.TransactionsTab, 'numeric');
            app.balanceEditField.Editable = 'off';
            app.Lamp2 = uilamp(app.TransactionsTab);
            app.GoalsTab = uitab(app.TabGroup);
            app.GoalsTab.Title = 'Goals';
            app.goalsUITable = uitable(app.GoalsTab);
            app.goalsUITable.ColumnName = {'amount'; 'category'; 'type'; 'date'};
            app.goalsUITable.RowName = {};
            app.goalsUITable.SelectionType = 'row';
            app.ReportsTab = uitab(app.TabGroup);
            app.ReportsTab.Title = 'Reports';
            app.reportAxes = uiaxes(app.ReportsTab);
            title(app.reportAxes, 'graphical report')
            xlabel(app.reportAxes, 'date')
            ylabel(app.reportAxes, 'balance')
            zlabel(app.reportAxes, 'Z')
            app.generatereportButton = uibutton(app.ReportsTab, 'push');
            app.generatereportButton.ButtonPushedFcn = createCallbackFcn(app, @generatereportButtonPushed, true);
            app.generatereportButton.Text = 'generate report';
            app.reportTextArea = uitextarea(app.ReportsTab);
            app.reportTextArea.Editable = 'off';
            app.categoryEditFieldLabel = uilabel(app.FinanceTrackerUIFigure);
            app.categoryEditFieldLabel.Tag = 'categoryEditFieldLabel';
            app.categoryEditFieldLabel.Text = 'category';
            app.categoryEditField = uieditfield(app.FinanceTrackerUIFigure, 'text');
            app.categoryEditField.CharacterLimits = [0 25];
            app.OKButton = uibutton(app.FinanceTrackerUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.valueEditFieldLabel = uilabel(app.FinanceTrackerUIFigure);
            app.valueEditFieldLabel.Text = 'value';
            app.valueEditField = uieditfield(app.FinanceTrackerUIFigure, 'numeric');
            app.dateDatePickerLabel = uilabel(app.FinanceTrackerUIFigure);
            app.dateDatePickerLabel.Text = 'date';
            app.dateDatePicker = uidatepicker(app.FinanceTrackerUIFigure);
            app.dateDatePicker.DisplayFormat = 'dd-MM-uuuu';
            app.categoryDropDownLabel = uilabel(app.FinanceTrackerUIFigure);
            app.categoryDropDownLabel.Tag = 'categoryDropDownLabel';
            app.categoryDropDownLabel.Visible = 'off';
            app.categoryDropDownLabel.Text = 'category';
            app.categoryDropDown = uidropdown(app.FinanceTrackerUIFigure);
            app.categoryDropDown.Items = {'food', 'bills', 'entertainment'};
            app.categoryDropDown.Visible = 'off';
            app.categoryDropDown.Value = 'food';
            app.typeDropDownLabel = uilabel(app.FinanceTrackerUIFigure);
            app.typeDropDownLabel.Text = 'type';
            % Show the figure after all components are created
            app.FinanceTrackerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finance_tracker_app_v4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FinanceTrackerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FinanceTrackerUIFigure)
        end
    end
end